classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Panel                      matlab.ui.container.Panel
        stop_Button                matlab.ui.control.Button
        play_Button                matlab.ui.control.Button
        samplerateEditField        matlab.ui.control.NumericEditField
        samplerateEditFieldLabel   matlab.ui.control.Label
        browseButton               matlab.ui.control.Button
        fileaddressEditField       matlab.ui.control.EditField
        fileaddressEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        filename;
        pathname;
        orginalaudiodata;
        fs=0;
        audio;
        
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: browseButton
        function browseButtonPushed(app, event)
            [file, Pathname]=uigetfile('*.wav','wav file selector');
            app.filename=file;
            app.pathname=strcat(file,Pathname);
            [app.orginalaudiodata,app.fs]=audioread(file);
            app.audio=app.orginalaudiodata;
            app.fileaddressEditField.Value=app.filename;
            app.samplerateEditField.Value=app.fs;
        end

        % Button pushed function: play_Button
        function play_ButtonPushed(app, event)
            clear sound
            sound(app.audio,app.orginalaudiodata);
        end

        % Button pushed function: stop_Button
        function stop_ButtonPushed(app, event)
            clear sound;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.fileaddressEditFieldLabel = uilabel(app.Panel);
            app.fileaddressEditFieldLabel.Text = 'file address';
            app.fileaddressEditField = uieditfield(app.Panel, 'text');
            app.fileaddressEditField.Placeholder = 'browse file';
            app.browseButton = uibutton(app.Panel, 'push');
            app.browseButton.ButtonPushedFcn = createCallbackFcn(app, @browseButtonPushed, true);
            app.browseButton.Text = 'browse';
            app.samplerateEditFieldLabel = uilabel(app.Panel);
            app.samplerateEditFieldLabel.Text = 'sample rate';
            app.samplerateEditField = uieditfield(app.Panel, 'numeric');
            app.play_Button = uibutton(app.Panel, 'push');
            app.play_Button.ButtonPushedFcn = createCallbackFcn(app, @play_ButtonPushed, true);
            app.play_Button.Icon = 'OIP.jpeg';
            app.play_Button.Text = '';
            app.stop_Button = uibutton(app.Panel, 'push');
            app.stop_Button.ButtonPushedFcn = createCallbackFcn(app, @stop_ButtonPushed, true);
            app.stop_Button.Icon = 'OIP (1).jpeg';
            app.stop_Button.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end